classdef UploadApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        FilesSelectedTextArea       matlab.ui.control.TextArea
        FilesSelectedTextAreaLabel  matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        MessageTextArea             matlab.ui.control.TextArea
        MessageTextAreaLabel        matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        UploadfilesButton           matlab.ui.control.Button
        SelectfilesButton           matlab.ui.control.Button
    end

    % Properties for storing data
    properties (Access = private)
        SelectedFilePath % Path of the selected file
        conn             % Database connection
        files
    end
    
    
    methods (Access = private)
        
        function updateTextArea(app, files)
            app.FilesSelectedTextArea.Value = files ;
            numLines = numel(app.FilesSelectedTextArea.Value);
            app.FilesSelectedTextArea.Position(4) = 22 * numLines; % adjust height based on number of lines
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            [app.files, path] = uigetfile('*.*', 'MultiSelect','on') ;
            if isequal(app.files, 0)
                disp('User selected Cancel');
            else
                if ischar(app.files)
                    app.files = {app.files}; % ensure files is in a cell array
                end
                app.files ;
                app.SelectedFilePath = fullfile(path, app.files);
                app.updateTextArea(app.files); % Display file names in the app window
                app.UploadfilesButton.Enable = true; % Enable the Upload button
                app.MessageTextArea.Value = 'Files selected. Ready to upload.';
            end
        end

        % Button pushed function: UploadfilesButton
        function UploadfilesButtonPushed(app, event)
            if isempty(app.SelectedFilePath)
                uialert(app.UIFigure, 'No file selected', 'Error');
                return;
            end
            
            % Turn both select and upload button off to indicate that the
            % app is working:
            app.SelectfilesButton.Enable = 'off';
            app.UploadfilesButton.Enable = 'off';
            app.MessageTextArea.Value = 'Starting the upload process...';

            % Establish a connection to the SQL Server
            % Define the DSN-less connection string
            connStr = 'Driver={SQL Server};Server=D54296\SQLEXPRESS;Database=mydatabase;Trusted_Connection=yes;';

            % Establish the database connection using the DSN-less connection string
            app.conn = odbc(connStr);

            if isopen(app.conn)

                % create a waitbar to show the progress
                h = waitbar(0, 'Reading and uploading files...') ;
                totalFiles = numel(app.SelectedFilePath);
                for i = 1:totalFiles
                    
                    % Update message for reading file
                    app.MessageTextArea.Value = sprintf('Reading file %d of %d...', i, totalFiles);

                    % Read files
                    fileID = i;
                    %fileData = uint8(fileread(app.SelectedFilePath{i}));
                    podfile = app.SelectedFilePath{i};
                    [poddata] = importpoddata(podfile, 'detstart', 0, 'maxdet', 10); % OBS: this is set to only read the first N data points to speed up tests and troubleshooting
                    
                    % Update message for import
                    app.MessageTextArea.Value = sprintf(['Imported ' num2str(length(poddata)) ' clicks']);

                    % Upload files
                    fileName = app.files{i};
                    fileName = strrep(fileName, ' ', ''); % remove spaces from file name
                    fileName = fileName(1:min(50, length(fileName)));

                    % Convert binary data to hexadecimal string for SQL insertion
                    %hexFileData = dec2hex(fileData);
                    % hexFileData = reshape(hexFileData', 1, []); % Convert to a single row
                    fileData=getByteStreamFromArray(poddata); % convert to byte array
                    hexFileData = reshape(dec2hex(fileData)', 1, []);% Convert to a single row

                    % Create the SQL query
                    sqlquery = sprintf('INSERT INTO Test (FileID, FileName, FileData) VALUES (%d, ''%s'', 0x%s)', fileID, fileName, hexFileData);

                    % Execute the SQL query with error handling
                    try
                        exec(app.conn, sqlquery);
                        
                    catch ME
                        fprintf('Error uploading', i, ME.message);
                    end

                    % Update waitbar
                    waitbar(i / totalFiles, h, sprintf('Processing file %d of %d...', i, totalFiles))
                end
                close(app.conn);
                % Close waitbar 
                close(h)
                uialert(app.UIFigure, 'Files uploaded successfully', 'Success');
            else
                uialert(app.UIFigure, ['Connection failed: ', app.conn.Message], 'Error');
            end

            % If upload is successful, disable the upload and enable the select button
            app.MessageTextArea.Value = 'Upload process completed.';
            app.SelectfilesButton.Enable = 'on';
            app.UploadfilesButton.Enable = 'off';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectfilesButton = uibutton(app.GridLayout, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select files';
            app.UploadfilesButton = uibutton(app.GridLayout, 'push');
            app.UploadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfilesButtonPushed, true);
            app.UploadfilesButton.Enable = 'off';
            app.UploadfilesButton.Text = 'Upload files';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MessageTextAreaLabel = uilabel(app.GridLayout3);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.GridLayout3);
            app.FilesSelectedTextAreaLabel = uilabel(app.GridLayout);
            app.FilesSelectedTextAreaLabel.Text = 'Files Selected';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UploadApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end