classdef VALKYRIE_v1 < matlab.apps.AppBase
    %VALKYRIE_V1 Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        FileselectionTab          matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        FilesSelectedTextArea_2   matlab.ui.control.TextArea
        SelectmetadatafileButton  matlab.ui.control.Button
        ConfirmselectionButton    matlab.ui.control.Button
        FilesSelectedTextArea     matlab.ui.control.TextArea
        SelectCFPODfilesButton    matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        MetadataTab               matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ProcessfilenamemetadatamatchButton  matlab.ui.control.Button
        ChangefilesButton         matlab.ui.control.Button
        OutputTab                 matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ReprocessdetectionmetadatamatchButton  matlab.ui.control.Button
        ReuploadmetadataButton    matlab.ui.control.Button
        SaveoutputfilesButton     matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        check            % Used for defining if CP1 or FP1 files are included
        SelectedFilePath % Path of the selected file
        files            % input file names
        path             % path to files to be used in CP3read_DTO   
        filtFiles        % filtered filenames
        cp1files
        cp3files
        fp1files
        fp3files
        Config
        minDate
        spec
        MetaData
        MetadataOverviewTable matlab.ui.control.Table

        Detections
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add paths to folder with configuration files and helper
            % scripts
            basePath = fileparts(mfilename('fullpath'));
            foldersToAdd = {'helpers', 'config', 'graphics'};
            
            for i = 1:numel(foldersToAdd)
                folderPath = fullfile(basePath, foldersToAdd{i});
                if isfolder(folderPath)
                    addpath(genpath(folderPath));
                else
                    warning('Folder not found: %s', folderPath);
                end
            end
            
            app.check = 1; % if = 1: will check if both C/FP1 and C/FP3 files are included

            % Load .json cofiguration file
            cfg = fullfile(basePath,'config','metadata_validation.json');
            try
                configText = fileread(cfg);
            catch
                uialert(app.UIFigure,  msg, 'MissingConfig');
                return
            end
            app.Config = jsondecode(configText);

            % Specify the minimum expected date for detection data
            app.minDate = datetime(app.Config.MinDate, 'TimeZone', 'UTC');

            app.spec = app.Config.MetadataSpec; % mandatory field names for metadata and the data type - it is a structure where each field is the columns names and each has a 1x1 struct with the field type (and options if field type is Predefined)

            % Specify which fields are used for metadata validation
            roles = struct();

            fields = fieldnames(app.spec);
            for f = 1:numel(fields)
                fname = fields{f};
                entry = app.spec.(fname);
                if isfield(entry,'Role')
                    roles.(entry.Role) = fname;  % map role → actual column name
                end
            end
            
            app.MetaRoles = roles;


            app.Detections = table();

        end

        % Button pushed function: SelectCFPODfilesButton
        function SelectCFPODfilesButtonPushed(app, event)
            % =====================
            % |POD file selection |
            % =====================
            % GOAL: Allow user to upload files from several deployments

            % TO DO: set a limit of files that can be uploaded at the same
            % time

            validExt = {'.CP1', '.CP3', '.FP1', '.FP3'};

            % Function where user can upload either single or multiple
            % files
            [app.files, app.path] = fileSelect(validExt);

            if isempty(app.files)
                return;
            end

            if ischar(app.files)
                app.files = {app.files}; % ensure files is in a cell array
            end
   
            %app.SelectedFilePath = cellfun(@(f) fullfile(app.path,f), app.files, 'UniformOutput', false);% Show selected POD files in TextArea
            app.FilesSelectedTextArea.Value = app.files;

            % Check that all file pairs are complete and only POD files are
            % selected
            [isValid, fileGroups, msg] = checkFileExtension(app.files, app.check); % app.check == 1 means that the function will evaluate if all pod pairs are complete

            if ~isValid
                uialert(app.UIFigure, msg, 'File Type Error');
                return;
            end
            
            % Store valid file sets and enable confirm
            app.cp1files = fileGroups.cp1;
            app.cp3files = fileGroups.cp3;
            app.fp1files = fileGroups.fp1;
            app.fp3files = fileGroups.fp3;
                
            % Filter for metadata entry (.CP3 or .FP3)
            isP3 = endsWith(app.files,'.CP3','IgnoreCase',true) | ...
                endsWith(app.files,'.FP3','IgnoreCase',true);
            app.filtFiles = app.files(isP3);

            app.SelectmetadatafileButton.Enable = 'on';

        end

        % Button pushed function: SelectmetadatafileButton
        function SelectmetadatafileButtonPushed(app, event)
            % ===============================
            % Metadata CSV file selection
            % ===============================
            
            [file, path] = uigetfile('*.csv', 'Select Metadata File');
            
            % If user cancels the dialog
            if isequal(file, 0)
                return;
            end
           
            % Optionally display or log filename
            app.FilesSelectedTextArea_2.Value = {file};
              
            %fullpath = fullfile(path, file); % get full path to be able to load the metadata file

            requiredFields = fieldnames(app.spec); % Get recquired field names

            try
                
                tbl = loadMetadataFile(fullfile(path, file)); % load metadata file - will give error if it fails
                
                checkMetadataColumns(tbl, requiredFields); % checks for recquired columns in metadata - will give error if it fails

                % Next check that datetime and coordinates are in correct format
                % - and change to correct format if not
                % TO DO: update validation scripts to match ETN metadata
                % names
                tbl = validateMetadata(tbl,app.minDate);  % Uses validateDatetime & validateCoordinates

                % Find all PODdata that has matching metadata and update
                % metadata file with match info
                tbl = matchFilenamesWithPOD(tbl, app.files);
                app.MetaData = tbl;

                app.ConfirmselectionButton.Enable = 'on';
            catch ME
                uialert(app.UIFigure, ME.message, messages('metadataError'));
            end


        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % When file selection has been confirmed... 
            % ===================================================
            % The user will be presented with a table of metadata. It is
            % color coded so matched metadata will have green rows and
            % unmatched metadata will have red rows. 
            % Two buttons at the bottom - one to process matched files and
            % one to go back (cancel) and upload other files and hope for a
            % better match. 

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadataTab;

            % Create the metadata overview to view matched metadata
            if isempty(app.MetadataOverviewTable) || ~isvalid(app.MetadataOverviewTable)
                app.MetadataOverviewTable = uitable(app.MetadataTab);
                app.MetadataOverviewTable.Position = [20 80 760 500];
                app.MetadataOverviewTable.ColumnSortable = true;
                app.MetadataOverviewTable.FontSize = 14;
            end

            % Add data to the table
            app.MetadataOverviewTable.Data = app.MetaData;

            % after setting Data:
            removeStyle(app.MetadataOverviewTable); % optional
            iY = find(strcmp(app.MetaData.MATCH,"Y"));
            iN = find(~strcmp(app.MetaData.MATCH,"Y"));
            sY = uistyle('BackgroundColor',[0.85 1.00 0.85]);
            sN = uistyle('BackgroundColor',[1.00 0.85 0.85]);
            if ~isempty(iY), addStyle(app.MetadataOverviewTable,sY,'row',iY); end
            if ~isempty(iN), addStyle(app.MetadataOverviewTable,sN,'row',iN); end

            % Disable buttons
            app.SelectCFPODfilesButton.Enable = 'off';
            app.ConfirmselectionButton.Enable = 'off';
            app.SelectmetadatafileButton.Enable = 'off';

            % Enable buttons
            app.ChangefilesButton.Enable = 'on';
            app.ProcessfilenamemetadatamatchButton.Enable = 'on';
           
        end

        % Button pushed function: ChangefilesButton
        function ChangefilesButtonPushed(app, event)
            %Function to allow user to go back and upload different
            %podfiles and/or metadata file

            % Switch to the file selction tab tab
            app.TabGroup.SelectedTab = app.FileselectionTab;

            % Reset file selection
            app.SelectCFPODfilesButton.Enable = 'on';
            app.ChangefilesButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';
            
            % Optionally clear metadata table
            if ~isempty(app.MetadataOverviewTable) && isvalid(app.MetadataOverviewTable)
                app.MetadataOverviewTable.Data = table();
            end

        end

        % Button pushed function: ProcessfilenamemetadatamatchButton
        function ProcessfilenamemetadatamatchButtonPushed(app, event)
            
            % Function to begin process to extract detections and create
            % detection output and metadata output for upload.


            % =======================
            % Extract and upload data
            % =======================

            % At the moment this will be the most labour-intensive chunk:
            %
            % 1. It extracts the POD data sequentially - using the
            % CPODread_DTO or FPODread_DTO depending on the input files.
            % 
            % 2. Extract and summarise the detection data required for the 
            % ETN database and reformat to match the database. The output
            % will be a single text file with all detections from all files
            % uploaded by a user - will have an identifier to match it to
            % metadata.
            %
            % 3. Gather the metadata input from the csv based on the
            % filename and generate a text file with metadata for all
            % processed files.
            %
            % 4. Push data to be ingested into the ETN database (TBD). It
            % will be send as text files: one with metadata info and one
            % with extracted detection data. OBS: first iteration of app
            % will generate detection and metadata file output for the user
            % to upload on their own into ETN. 

            % ===========================================================
            
            % Disable Change Files button
            app.ChangefilesButton.Enable = 'off';

            % Display message when the upload button is pressed
            app.TextArea.Value = messages('importStart');

            %================
            % Import POD data
            %================

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type

            % Create progress dialogue
            
            d = uiprogressdlg(app.UIFigure, 'Title', 'Processing Files', ...
                'Message', 'Starting...', 'Indeterminate', 'off');

            
            % TO DO: set up error messages to be displayed:
            % % Found error in extracting the data in the POD reader


            % Specififies if the nall will be calculated (only if C/FP1
            % files also uploaded.
            n = '';
            if app.check == 1
                n = '-n';
            end
            
            % Create a combined table with detections - identifiable by the
            % filename:
            for i = 1:numel(app.filtFiles)
                

                % Update progress
                d.Value = i / numel(app.filtFiles);
                d.Message = sprintf('Processing file %d of %d...', i, numel(app.filtFiles));

                % Update message for reading file
                app.TextArea.Value = messages('readingFile', i, numel(app.filtFiles));
               
                % get filename and extension
                [~, filename, ext] = fileparts(app.filtFiles{i});


                % ====================================
                % Read files and summarising functions
                % ====================================
                % Get the filepath to the 
                % % extract and format files depending on their extension
                try % Wrapped in a try-catch to ensure app will not crash if the import and formatting doesn't work for one of the files
                    switch ext
                        case '.CP3'
                            % Import CP3 (and CP1) file and convert to ETN format
                            app.TextArea.Value = messages('fileImported', 'CP3');
                            outputminutes = CP3read_DTO(app.path,filename,n);
                            app.TextArea.Value = messages('fileFormatted', 'CP3');
                            ETN = CP3read_2_etn(outputminutes);
                        case '.FP3'
                            % Import FP3 (and FP1) file and convert to ETN
                            % format
                            app.TextArea.Value = messages('fileImported', 'FP3');
                            outputminutes = FP3read_DTO(app.path, filename, n);
                            app.TextArea.Value = messages('fileFormatted', 'FP3');
                            ETN = CP3read_2_etn(outputminutes);
                    end
                catch ME 
                    uialert(app.UIFigure, ...
                        messages('fileError', filename, ME.message), ...
                        'File Processing Error');
                    continue; % SKip to next file
                end


                ETN = addvars(ETN, repmat({filename}, height(ETN), 1), 'NewVariableNames', 'filename', 'Before', 1); % add filename identifier
                ETN = addvars(ETN, repmat({ext}, height(ETN), 1), 'NewVariableNames', 'podType', 'Before', 1); % add column with pod type

                % TO DO: Check if match between metadata and detections
                % - and what to do if there is a mismatch!
                %if dateshift(app.Active_datetime,'start', 'day') ~= dateshift(ETN.time(1), 'start', 'day') && dateshift(app.Valid_data_until_datetime,'start', 'day') ~= dateshift(ETN.time(end), 'start', 'day')
                %    
                %end

                % Append detection data
                if isempty(app.Detections)
                    app.Detections = ETN;
                else
                    app.Detections = [app.Detections; ETN];
                end

            end

            % Close progress dialogue
            close(d);
            
            % Enable Save Output button
            app.SaveoutputfilesButton.Enable = 'on';

            % Switch to the output tab
            app.TabGroup.SelectedTab = app.OutputTab;

            disp(height(app.Detections)) %DEBUGGING
            
        end

        % Button pushed function: ReuploadmetadataButton
        function ReuploadmetadataButtonPushed(app, event)
            % TO DO: add code for this button function
        end

        % Button pushed function: ReprocessdetectionmetadatamatchButton
        function ReprocessdetectionmetadatamatchButtonPushed(app, event)
            % TO DO: add code for this button function
        end

        % Button pushed function: SaveoutputfilesButton
        function SaveoutputfilesButtonPushed(app, event)
            
            % Save cleaned metadata
            % Ask user for base filename and folder
            % TO DO: Does ETN have a recquirement for the file names for
            % detections and metadata
            [baseFile, basePath] = uiputfile('*.csv', 'Save output files as');
            if ischar(baseFile)
                % Remove .csv extension if present
                [~, nameOnly, ~] = fileparts(baseFile);
            
                % Save cleaned metadata
                cleanedMeta = app.MetaData(app.MetaData.MATCH == "Y", :);
                writetable(cleanedMeta, fullfile(basePath, [nameOnly '_metadata.csv']));
            
                % Save combined detections
                writetable(app.Detections, fullfile(basePath, [nameOnly '_detections.csv']));
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; 'VALKYRIE v1.0'; ''; 'Please select the raw C-POD (.CP1 and .CP3) and F-POD (.FP1 and .FP3) files to upload along with a .csv file of the corresponding metadata. Please refer to ETN for the correct format for the metadata input.'};
            app.SelectCFPODfilesButton = uibutton(app.GridLayout, 'push');
            app.SelectCFPODfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCFPODfilesButtonPushed, true);
            app.SelectCFPODfilesButton.Tooltip = {'Click to select .CP1, .CP3, .FP1 and/or .FP3 files to upload.'};
            app.SelectCFPODfilesButton.Text = 'Select C-/F-POD files';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Confirm selection of files.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.SelectmetadatafileButton = uibutton(app.GridLayout, 'push');
            app.SelectmetadatafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetadatafileButtonPushed, true);
            app.SelectmetadatafileButton.Enable = 'off';
            app.SelectmetadatafileButton.Tooltip = {'Click to select metadata .csv file to upload.'};
            app.SelectmetadatafileButton.Text = 'Select metadata file';
            app.FilesSelectedTextArea_2 = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea_2.Editable = 'off';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.GridLayout2 = uigridlayout(app.MetadataTab);
            app.ChangefilesButton = uibutton(app.GridLayout2, 'push');
            app.ChangefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefilesButtonPushed, true);
            app.ChangefilesButton.Enable = 'off';
            app.ChangefilesButton.Text = 'Change files';
            app.ProcessfilenamemetadatamatchButton = uibutton(app.GridLayout2, 'push');
            app.ProcessfilenamemetadatamatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfilenamemetadatamatchButtonPushed, true);
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Text = 'Process filename metadata match';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.GridLayout3 = uigridlayout(app.OutputTab);
            app.SaveoutputfilesButton = uibutton(app.GridLayout3, 'push');
            app.SaveoutputfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutputfilesButtonPushed, true);
            app.SaveoutputfilesButton.Enable = 'off';
            app.SaveoutputfilesButton.Text = 'Save output files ';
            app.ReuploadmetadataButton = uibutton(app.GridLayout3, 'push');
            app.ReuploadmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ReuploadmetadataButtonPushed, true);
            app.ReuploadmetadataButton.Enable = 'off';
            app.ReuploadmetadataButton.Text = 'Re-upload metadata';
            app.ReprocessdetectionmetadatamatchButton = uibutton(app.GridLayout3, 'push');
            app.ReprocessdetectionmetadatamatchButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessdetectionmetadatamatchButtonPushed, true);
            app.ReprocessdetectionmetadatamatchButton.Enable = 'off';
            app.ReprocessdetectionmetadatamatchButton.Text = 'Re-process detection metadata match';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VALKYRIE_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end